classdef Fitting_MTPA_Coefficient2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FittingMTPACoefficientUIFigure  matlab.ui.Figure
        LdEditFieldLabel                matlab.ui.control.Label
        LdEditField                     matlab.ui.control.NumericEditField
        LqEditFieldLabel                matlab.ui.control.Label
        LqEditField                     matlab.ui.control.NumericEditField
        psi_fEditFieldLabel             matlab.ui.control.Label
        psi_fEditField                  matlab.ui.control.NumericEditField
        id_limitEditFieldLabel          matlab.ui.control.Label
        id_limitEditField               matlab.ui.control.NumericEditField
        StartCalButton                  matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        idfTeTextAreaLabel              matlab.ui.control.Label
        idfTeTextArea                   matlab.ui.control.TextArea
        Num_polynominalLabel            matlab.ui.control.Label
        num_polyEditField               matlab.ui.control.NumericEditField
        FittingMTPACoefficientLabel     matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        npEditFieldLabel                matlab.ui.control.Label
        npEditField                     matlab.ui.control.NumericEditField
        iqgTeTextAreaLabel              matlab.ui.control.Label
        iqgTeTextArea                   matlab.ui.control.TextArea
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes2_2                       matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Button pushed function: StartCalButton
        function StartCalButtonPushed(app, event)
           cla(app.UIAxes,'reset');
           cla(app.UIAxes2,'reset');
           cla(app.UIAxes2_2,'reset');
            %参数赋值
            Ld = app.LdEditField.Value;                  %Ld = get(app.LdEditField,'value');
            Lq = app.LqEditField.Value;                         %Lq = get(app.LqEditField,'value');
            psi_f = app.psi_fEditField.Value;                         %psi_f =  get(app.psi_fEditField,'value');
            id_limit = app.id_limitEditField.Value;                         %id_limit= get(app.id_limitEditField,'value');
            Ld_Lq=Ld-Lq;
            
            i_d=0:-2:id_limit;
            num_id=length(i_d);
                
            for k=1:1:num_id
               i_q(k)=sqrt((psi_f * i_d(k) + Ld_Lq*(i_d(k))^2)/Ld_Lq);
               
            end
            
            n_p=app.npEditField.Value;
            Te=1.5*n_p*i_q.*[psi_f+Ld_Lq*i_d];
            plot(app.UIAxes,i_d,i_q,'displayname','MTPA','linewidth',2);
            
            num_polynominal=app.num_polyEditField.Value;
            Coefficient_idTe=polyfit(Te,i_d,num_polynominal);
            Coefficient_iqTe=polyfit(Te,i_q,num_polynominal);
%             Coefficient_idiq=polyfit(id,iq,num_polynominal);
                        
            app.idfTeTextArea.Value=num2str(Coefficient_idTe);  
            app.iqgTeTextArea.Value=num2str(Coefficient_iqTe);  
            
            i_d1=polyval(Coefficient_idTe,Te);
            i_q1=polyval(Coefficient_iqTe,Te);
            
%             绘制id=f(Te)的figure
            plot(app.UIAxes2,Te,i_d,'linewidth',2);
            hold(app.UIAxes2,'on');
            plot(app.UIAxes2,Te,i_d1,'linewidth',2);
%            绘制iq=g(Te)的figure
            plot(app.UIAxes2_2,Te,i_q,'linewidth',2);
            hold(app.UIAxes2_2,'on');
            plot(app.UIAxes2_2,Te,i_q1,'linewidth',2);
            
            
%             y=polyval(Coefficient,i_d);
%             hold(app.UIAxes,'on');
%             plot(app.UIAxes,i_d,y,'displayname','New','linewidth',2);
            legend(app.UIAxes);
       
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FittingMTPACoefficientUIFigure = uifigure;
            app.FittingMTPACoefficientUIFigure.Name = 'Fitting MTPA Coefficient';
            app.LdEditFieldLabel = uilabel(app.FittingMTPACoefficientUIFigure);
            app.LdEditFieldLabel.Text = 'Ld';
            app.LdEditField = uieditfield(app.FittingMTPACoefficientUIFigure, 'numeric');
            app.LdEditField.Value = 6e-05;
            app.LqEditFieldLabel = uilabel(app.FittingMTPACoefficientUIFigure);
            app.LqEditFieldLabel.Text = 'Lq';
            app.LqEditField = uieditfield(app.FittingMTPACoefficientUIFigure, 'numeric');
            app.LqEditField.Value = 0.00012;
            app.psi_fEditFieldLabel = uilabel(app.FittingMTPACoefficientUIFigure);
            app.psi_fEditFieldLabel.Text = 'psi_f';
            app.psi_fEditField = uieditfield(app.FittingMTPACoefficientUIFigure, 'numeric');
            app.psi_fEditField.Value = 0.03;
            app.id_limitEditFieldLabel = uilabel(app.FittingMTPACoefficientUIFigure);
            app.id_limitEditFieldLabel.Text = 'id_limit';
            app.id_limitEditField = uieditfield(app.FittingMTPACoefficientUIFigure, 'numeric');
            app.id_limitEditField.Value = -300;
            app.StartCalButton = uibutton(app.FittingMTPACoefficientUIFigure, 'push');
            app.StartCalButton.ButtonPushedFcn = createCallbackFcn(app, @StartCalButtonPushed, true);
            app.StartCalButton.Text = 'StartCal';
            app.UIAxes = uiaxes(app.FittingMTPACoefficientUIFigure);
            title(app.UIAxes, 'i_d-i_q')
            xlabel(app.UIAxes, 'i_d')
            ylabel(app.UIAxes, 'i_q')
            app.idfTeTextAreaLabel = uilabel(app.FittingMTPACoefficientUIFigure);
            app.idfTeTextAreaLabel.Text = 'id=f(Te)';
            app.idfTeTextArea = uitextarea(app.FittingMTPACoefficientUIFigure);
            app.Num_polynominalLabel = uilabel(app.FittingMTPACoefficientUIFigure);
            app.Num_polynominalLabel.Text = 'Num_polynominal';
            app.num_polyEditField = uieditfield(app.FittingMTPACoefficientUIFigure, 'numeric');
            app.num_polyEditField.Value = 3;
            app.FittingMTPACoefficientLabel = uilabel(app.FittingMTPACoefficientUIFigure);
            app.FittingMTPACoefficientLabel.Text = 'Fitting MTPA Coefficient';
            app.Label = uilabel(app.FittingMTPACoefficientUIFigure);
            app.Label.Text = '多项式次数';
            app.Label_2 = uilabel(app.FittingMTPACoefficientUIFigure);
            app.Label_2.Text = '多项式系数';
            app.npEditFieldLabel = uilabel(app.FittingMTPACoefficientUIFigure);
            app.npEditFieldLabel.Text = 'np';
            app.npEditField = uieditfield(app.FittingMTPACoefficientUIFigure, 'numeric');
            app.npEditField.Value = 4;
            app.iqgTeTextAreaLabel = uilabel(app.FittingMTPACoefficientUIFigure);
            app.iqgTeTextAreaLabel.Text = 'iq=g(Te)';
            app.iqgTeTextArea = uitextarea(app.FittingMTPACoefficientUIFigure);
            app.UIAxes2 = uiaxes(app.FittingMTPACoefficientUIFigure);
            title(app.UIAxes2, 'T_e-i_d')
            xlabel(app.UIAxes2, 'T_e')
            ylabel(app.UIAxes2, 'i_d')
            app.UIAxes2_2 = uiaxes(app.FittingMTPACoefficientUIFigure);
            title(app.UIAxes2_2, 'T_e-i_q')
            xlabel(app.UIAxes2_2, 'T_e')
            ylabel(app.UIAxes2_2, 'i_q')
        end
    end

    methods (Access = public)

        % Construct app
        function app = Fitting_MTPA_Coefficient2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FittingMTPACoefficientUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FittingMTPACoefficientUIFigure)
        end
    end
end